classdef Function_analysis_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Roots             matlab.ui.control.CheckBox
        Extremes          matlab.ui.control.CheckBox
        Infection_points  matlab.ui.control.CheckBox
        MATLABLabel       matlab.ui.control.Label
        Function          matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        From              matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        To                matlab.ui.control.NumericEditField
        Interval          matlab.ui.control.Label
        Analysis          matlab.ui.control.Button
        Y                 matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Analysis
        function AnalysisButtonPushed(app, event)
            syms x;            
            interval_from = app.From.Value;
            interval_to = app.To.Value;
            f = app.Function.Value;
            fplot(app.UIAxes, f, [interval_from interval_to], "Color","k")
            hold(app.UIAxes, "on");

            f = str2sym(app.Function.Value);
            root = app.Roots.Value;
            extr = app.Extremes.Value;
            inf_point = app.Infection_points.Value;


            A = solve(f,x);
            a = double(A);
            points_1 = plot(app.UIAxes, a, subs(f,x,a),'r*');
          hold (app.UIAxes,'on')
            if (root==false)
                delete(points_1);
            end
            
            
            g = diff(f);
            B = solve(g,x);
            b = double(B);
            points_2 = plot(app.UIAxes, b, subs(f,x,b),'g*')
          hold (app.UIAxes,'on')    
            if (extr==false)
                delete(points_2);
            end
            
            
                d = diff(g);
                C = solve(d,x);
                c = double(C);
                points_3 = plot(app.UIAxes, c, subs(f,x,c),'b*')
          hold (app.UIAxes,'on')    
            if (inf_point==false)
                delete(points_3);
            end
            hold (app.UIAxes,'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Roots = uicheckbox(app.UIFigure);
            app.Roots.Text = 'Корни';
            app.Extremes = uicheckbox(app.UIFigure);
            app.Extremes.Text = 'Экстремумы';
            app.Infection_points = uicheckbox(app.UIFigure);
            app.Infection_points.Text = 'Точки перегиба';
            app.MATLABLabel = uilabel(app.UIFigure);
            app.MATLABLabel.Text = {'Введите свою функцию'; '(Пожалуйста, используйте '; 'терминологию MATLAB):'};
            app.Function = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'ОТ';
            app.From = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'ДО';
            app.To = uieditfield(app.UIFigure, 'numeric');
            app.Interval = uilabel(app.UIFigure);
            app.Interval.Text = {'Введите интервал '; 'на котором вы хотите '; 'построить график:'};
            app.Analysis = uibutton(app.UIFigure, 'push');
            app.Analysis.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.Analysis.Text = 'Анализ';
            app.Y = uilabel(app.UIFigure);
            app.Y.Text = 'Y = ';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'Отметьте, что вы хотите найти '; 'и нажмите "Анализ".'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Ваша Функция')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Function_analysis_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end